classdef BPSK_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SNREditFieldLabel         matlab.ui.control.Label
        SNREditField              matlab.ui.control.NumericEditField
        INSTRUCTIONTextAreaLabel  matlab.ui.control.Label
        INSTRUCTIONTextArea       matlab.ui.control.TextArea
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


bitRate = 1000; 
symbolRate = 2 * bitRate;
SNRdB = app.SNREditField.Value;
demodulatedDuration = 5; % Recorded Voice Duration

% Record voice input
recorder = audiorecorder; 
disp('Start speaking...');
recordblocking(recorder, demodulatedDuration); 
disp('Recording complete.');

% Get recorded voice
voice = getaudiodata(recorder);
sampleRate = recorder.SampleRate;

% Normalize voice signal
voice = voice / max(abs(voice));

% Convert voice signal to bits
bits = reshape(dec2bin(round((voice + 1) / 2)), 1, []);

% BPSK modulation
symbols = 2 * (bits - '0') - 1;

% Add noise
noisePower = 10^(-SNRdB/10); 
noise = sqrt(noisePower) * randn(1, length(symbols));
receivedSymbols = symbols + noise;

% BPSK demodulation
receivedBits = (receivedSymbols > 0);
% Convert received bits back to voice signal
receivedVoice = reshape(char(receivedBits + '0'), 8, []).';
receivedVoice = bin2dec(receivedVoice);
receivedVoice = receivedVoice / max(abs(receivedVoice));

% Adjust demodulated voice duration to desired length
desiredLength = round(demodulatedDuration * sampleRate);
receivedVoice = resample(receivedVoice, desiredLength, length(receivedVoice));

sound(voice, sampleRate);
pause(demodulatedDuration);

sound(receivedVoice, sampleRate);

t = (0:length(voice)-1) / sampleRate;
t_received = (0:length(receivedVoice)-1) / sampleRate;

%subplot(2,1,1);
plot(app.UIAxes,t, voice, 'b');
hold on;
plot(app.UIAxes2,t_received, receivedVoice, 'g');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Demodulated Voice')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Input Voice')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.INSTRUCTIONTextArea = uitextarea(app.UIFigure);
            app.INSTRUCTIONTextArea.Value = {'1.At 1st enter the SNR'; '2.By pressing start button button it will take input voice signal for 5 sec.'; '3.Then modulate it by BPSK modulation Technique'; '4.Finally it give the output.'; '5.To see the input and out signal graph press the corresponding graph.'; ''; 'Please Speak for 5 Sec after Pressing Start Button'};
            app.INSTRUCTIONTextAreaLabel = uilabel(app.UIFigure);
            app.INSTRUCTIONTextAreaLabel.Text = 'INSTRUCTION';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BPSK_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end